classdef HB11TEA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        InputsTab                  matlab.ui.container.Tab
        OutputsTab                 matlab.ui.container.Tab
        GridLayout                 matlab.ui.container.GridLayout
        OutputPanel                matlab.ui.container.Panel
        OutputNavTree              matlab.ui.container.Tree
        ProcessNode                matlab.ui.container.TreeNode
        ElectricityGenerationNode  matlab.ui.container.TreeNode
        EconomicNode               matlab.ui.container.TreeNode
        InvestmentSummaryNode      matlab.ui.container.TreeNode
        CapitalCostsNode           matlab.ui.container.TreeNode
        OperatingCostsNode         matlab.ui.container.TreeNode
        OptionsTab                 matlab.ui.container.Tab
        ContextMenu                matlab.ui.container.ContextMenu
        Menu                       matlab.ui.container.Menu
        Menu2                      matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: OutputNavTree
        function OutputNavTreeSelectionChanged(app, event)
            
            % Array of selected TreeNodes
            selectedNodes = app.OutputNavTree.SelectedNodes;

            % --- Check if the node is a parent (Has Children) ---
            if ~isempty(selectedNodes.Children)
                return;
            end
            
            % --- Change the Title ---
            selectedEquationName = selectedNodes.Text;

            app.OutputPanel.Title = selectedEquationName;

            % --- Change the Rendered Options ---

            % Clear all selected panels
            set(app.OutputPanel.Children, 'Visible', 'off');
            
            % Conditionally show a child panel
            switch selectedEquationName
                    
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.InputsTab = uitab(app.TabGroup);
            app.InputsTab.Title = 'Inputs';
            app.OutputsTab = uitab(app.TabGroup);
            app.OutputsTab.Title = 'Outputs';
            app.GridLayout = uigridlayout(app.OutputsTab);
            app.OutputNavTree = uitree(app.GridLayout);
            app.OutputNavTree.SelectionChangedFcn = createCallbackFcn(app, @OutputNavTreeSelectionChanged, true);
            app.ProcessNode = uitreenode(app.OutputNavTree);
            app.ProcessNode.Text = 'Process';
            app.ElectricityGenerationNode = uitreenode(app.ProcessNode);
            app.ElectricityGenerationNode.Text = 'Electricity Generation';
            app.EconomicNode = uitreenode(app.OutputNavTree);
            app.EconomicNode.Text = 'Economic';
            app.InvestmentSummaryNode = uitreenode(app.EconomicNode);
            app.InvestmentSummaryNode.Text = 'Investment Summary';
            app.CapitalCostsNode = uitreenode(app.EconomicNode);
            app.CapitalCostsNode.Text = 'Capital Costs';
            app.OperatingCostsNode = uitreenode(app.EconomicNode);
            app.OperatingCostsNode.Text = 'Operating Costs';
            app.OutputPanel = uipanel(app.GridLayout);
            app.OutputPanel.Title = 'Output';
            app.OptionsTab = uitab(app.TabGroup);
            app.OptionsTab.Title = 'Options';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HB11TEA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end